classdef GUI_Scripts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        PginaPrincipalTab               matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        GuiadeUsuarioTab                matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Image7                          matlab.ui.control.Image
        EJEMPLODEUSOLabel               matlab.ui.control.Label
        CaracterizacinPCMPAMporSNRqTab  matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        PCMPAMScriptPanel               matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        CalculodeNivelesPanel           matlab.ui.container.Panel
        ObtenerNivelesyBitsporSimboloButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SNRqdBSpinnerLabel              matlab.ui.control.Label
        SNRqdBSpinner                   matlab.ui.control.Spinner
        InserteelSNRdecuantizacinLabel  matlab.ui.control.Label
        SealdeVozPanel                  matlab.ui.container.Panel
        A1SpinnerLabel                  matlab.ui.control.Label
        A1Spinner                       matlab.ui.control.Spinner
        A2SpinnerLabel                  matlab.ui.control.Label
        A2Spinner                       matlab.ui.control.Spinner
        A3SpinnerLabel                  matlab.ui.control.Label
        A3Spinner                       matlab.ui.control.Spinner
        A4SpinnerLabel                  matlab.ui.control.Label
        A4Spinner                       matlab.ui.control.Spinner
        CalcularSealdeVozyParametrosButton  matlab.ui.control.Button
        f1SpinnerLabel                  matlab.ui.control.Label
        f1Spinner                       matlab.ui.control.Spinner
        f2SpinnerLabel                  matlab.ui.control.Label
        f2Spinner                       matlab.ui.control.Spinner
        f3SpinnerLabel                  matlab.ui.control.Label
        f3Spinner                       matlab.ui.control.Spinner
        f4SpinnerLabel                  matlab.ui.control.Label
        f4Spinner                       matlab.ui.control.Spinner
        ParmetrosCaracteristicosPanel   matlab.ui.container.Panel
        RbbitssegundoSpinnerLabel       matlab.ui.control.Label
        RbbitssegundoSpinner            matlab.ui.control.Spinner
        BpcmHzSpinnerLabel              matlab.ui.control.Label
        BpcmHzSpinner                   matlab.ui.control.Spinner
        MPAMRsbaudiosSpinnerLabel       matlab.ui.control.Label
        MPAMRsbaudiosSpinner            matlab.ui.control.Spinner
        BpamHzSpinnerLabel              matlab.ui.control.Label
        BpamHzSpinner                   matlab.ui.control.Spinner
        EntropiaBitsCaracterSpinnerLabel  matlab.ui.control.Label
        EntropiaBitsCaracterSpinner     matlab.ui.control.Spinner
        EntropianatsSpinnerLabel        matlab.ui.control.Label
        EntropianatsSpinner             matlab.ui.control.Spinner
        TabGroup2                       matlab.ui.container.TabGroup
        SealdeVozAnalgicaTab            matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        SealCuantificadaTab             matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        SealDigitalTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes2_2                       matlab.ui.control.UIAxes
        TramaGeneradaEditFieldLabel     matlab.ui.control.Label
        TramaGeneradaEditField          matlab.ui.control.EditField
        DominioFrecuencialTab           matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Image2                          matlab.ui.control.Image
        CaracterizacinPCMPAMconExcesodeVppTab  matlab.ui.container.Tab
        CaracterizacinPCMPAMconExcesodeVPPPanel  matlab.ui.container.Panel
        DatosdeEntradaPanel             matlab.ui.container.Panel
        FrecuenciafmEditFieldLabel      matlab.ui.control.Label
        FrecuenciafmEditField           matlab.ui.control.NumericEditField
        VppEditFieldLabel               matlab.ui.control.Label
        VppEditField                    matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeMuestreoEditField   matlab.ui.control.NumericEditField
        ErrordeCuantizacinEditFieldLabel  matlab.ui.control.Label
        ErrordeCuantizacinEditField     matlab.ui.control.NumericEditField
        MPAMEditFieldLabel              matlab.ui.control.Label
        MPAMEditField                   matlab.ui.control.NumericEditField
        CalcularParmetrosButton         matlab.ui.control.Button
        ParmetrosCaracteristicosPanel_2  matlab.ui.container.Panel
        RbbitssegundoSpinner_2Label     matlab.ui.control.Label
        RbbitssegundoSpinner_2          matlab.ui.control.Spinner
        BpcmHzSpinner_2Label            matlab.ui.control.Label
        BpcmHzSpinner_2                 matlab.ui.control.Spinner
        MPAMRsbaudiosSpinner_2Label     matlab.ui.control.Label
        MPAMRsbaudiosSpinner_2          matlab.ui.control.Spinner
        BpamHzSpinner_2Label            matlab.ui.control.Label
        BpamHzSpinner_2                 matlab.ui.control.Spinner
        EntropiaBitsCaracterSpinner_2Label  matlab.ui.control.Label
        EntropiaBitsCaracterSpinner_2   matlab.ui.control.Spinner
        EntropianatsSpinner_2Label      matlab.ui.control.Label
        EntropianatsSpinner_2           matlab.ui.control.Spinner
        UIAxes5                         matlab.ui.control.UIAxes
        UITable2                        matlab.ui.control.Table
        TramaPCMEditField_2Label        matlab.ui.control.Label
        TramaPCMEditField_2             matlab.ui.control.EditField
        TramaPAMEditFieldLabel          matlab.ui.control.Label
        TramaPAMEditField               matlab.ui.control.EditField
        nbitsPCMwordSpinnerLabel        matlab.ui.control.Label
        nbitsPCMwordSpinner             matlab.ui.control.Spinner
        kbitssimbolosSpinnerLabel       matlab.ui.control.Label
        kbitssimbolosSpinner            matlab.ui.control.Spinner
        EntropiaeInformacindeunMensajeTab  matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        DatosdeEntradaPanel_2           matlab.ui.container.Panel
        INSERTESUMENSAJETextAreaLabel   matlab.ui.control.Label
        INSERTESUMENSAJETextArea        matlab.ui.control.TextArea
        CalcularEntropiaButton          matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        EntropiaSpinnerLabel            matlab.ui.control.Label
        EntropiaSpinner                 matlab.ui.control.Spinner
        UITable3                        matlab.ui.control.Table
        TotaldeLetrasSpinnerLabel       matlab.ui.control.Label
        TotaldeLetrasSpinner            matlab.ui.control.Spinner
        TransformacinacodigoASCIIPanel  matlab.ui.container.Panel
        TramadeBitsEditFieldLabel       matlab.ui.control.Label
        TramadeBitsEditField            matlab.ui.control.EditField
        EntropiaSpinner_2Label          matlab.ui.control.Label
        EntropiaSpinner_2               matlab.ui.control.Spinner
        CalidadyCalculodeunEnlaceTab    matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        Image5                          matlab.ui.control.Image
        DatosdeEntradaPanel_4           matlab.ui.container.Panel
        PotenciaTXWSpinnerLabel         matlab.ui.control.Label
        PotenciaTXWSpinner              matlab.ui.control.Spinner
        CoeficientedeAtenuacindBmSpinnerLabel  matlab.ui.control.Label
        CoeficientedeAtenuacindBmSpinner  matlab.ui.control.Spinner
        DistanciadeLineadeTransmisind1mSpinnerLabel  matlab.ui.control.Label
        DistanciadeLineadeTransmisind1mSpinner  matlab.ui.control.Spinner
        DistanciaentreAntenasDmSpinnerLabel  matlab.ui.control.Label
        DistanciaentreAntenasDmSpinner  matlab.ui.control.Spinner
        GananciadeTransmisinGTXdBdSpinnerLabel  matlab.ui.control.Label
        GananciadeTransmisinGTXdBdSpinner  matlab.ui.control.Spinner
        FrecuenciaPortadorafcHzSpinnerLabel  matlab.ui.control.Label
        FrecuenciaPortadorafcHzSpinner  matlab.ui.control.Spinner
        GananciadeRecepcinGRXdBiSpinnerLabel  matlab.ui.control.Label
        GananciadeRecepcinGRXdBiSpinner  matlab.ui.control.Spinner
        DistanciadeLineadeTransmisind2mSpinnerLabel  matlab.ui.control.Label
        DistanciadeLineadeTransmisind2mSpinner  matlab.ui.control.Spinner
        CalcularParmetrosdeAnlisisButton  matlab.ui.control.Button
        SensitividadReceptivadBSpinnerLabel  matlab.ui.control.Label
        SensitividadReceptivadBSpinner  matlab.ui.control.Spinner
        ResultadosPanel_3               matlab.ui.container.Panel
        PIREdBmSpinnerLabel             matlab.ui.control.Label
        PIREdBmSpinner                  matlab.ui.control.Spinner
        PotenciadeRecepcindBmSpinnerLabel  matlab.ui.control.Label
        PotenciadeRecepcindBmSpinner    matlab.ui.control.Spinner
        ExisteEnlaceLabel               matlab.ui.control.Label
        CalidadLabel                    matlab.ui.control.Label
        PotenciaeEntropiadelRuidoTab_3  matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        DatosdeEntradaPanel_3           matlab.ui.container.Panel
        MediaSpinnerLabel               matlab.ui.control.Label
        MediaSpinner                    matlab.ui.control.Spinner
        DesviacinEstandarSpinnerLabel   matlab.ui.control.Label
        DesviacinEstandarSpinner        matlab.ui.control.Spinner
        CalculodePotenciasButton        matlab.ui.control.Button
        ResultadosPanel_2               matlab.ui.container.Panel
        PotenciaContinuaSpinnerLabel    matlab.ui.control.Label
        PotenciaContinuaSpinner         matlab.ui.control.Spinner
        PotenciaAltenaSpinnerLabel      matlab.ui.control.Label
        PotenciaAltenaSpinner           matlab.ui.control.Spinner
        PotenciaTotalSpinnerLabel       matlab.ui.control.Label
        PotenciaTotalSpinner            matlab.ui.control.Spinner
        EntropiaSpinner_3Label          matlab.ui.control.Label
        EntropiaSpinner_3               matlab.ui.control.Spinner
        UIAxes6                         matlab.ui.control.UIAxes
        Image3                          matlab.ui.control.Image
        InformacinMutuadeCanalesBSCBECTab  matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        CapacidaddeCanalporShannonPanel  matlab.ui.container.Panel
        AnchodeBandaHzSpinnerLabel      matlab.ui.control.Label
        AnchodeBandaHzSpinner           matlab.ui.control.Spinner
        SNRdBSpinnerLabel               matlab.ui.control.Label
        SNRdBSpinner                    matlab.ui.control.Spinner
        CalcularCapacidaddelCanalButton  matlab.ui.control.Button
        CapacidadbitssegundoSpinnerLabel  matlab.ui.control.Label
        CapacidadbitssegundoSpinner     matlab.ui.control.Spinner
        CapacidaddeCanalPanel           matlab.ui.container.Panel
        CodigoPCMEditFieldLabel         matlab.ui.control.Label
        CodigoPCMEditField              matlab.ui.control.EditField
        AmplitudEditFieldLabel          matlab.ui.control.Label
        AmplitudEditField               matlab.ui.control.EditField
        AnchodeBandadelCanalEditFieldLabel  matlab.ui.control.Label
        AnchodeBandadelCanalEditField   matlab.ui.control.EditField
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        GraficarTramayEntropiaButton    matlab.ui.control.Button
        CanalBSCPanel                   matlab.ui.container.Panel
        CodigoPCMEditField_2Label       matlab.ui.control.Label
        CodigoPCMEditField_2            matlab.ui.control.EditField
        ProbabilidaddeErrorEditFieldLabel  matlab.ui.control.Label
        ProbabilidaddeErrorEditField    matlab.ui.control.EditField
        CalcularCaracteristicasdeCanalButton_2  matlab.ui.control.Button
        HAbitssimboloSpinnerLabel       matlab.ui.control.Label
        HAbitssimboloSpinner            matlab.ui.control.Spinner
        HABSpinnerLabel                 matlab.ui.control.Label
        HABSpinner                      matlab.ui.control.Spinner
        IABSpinnerLabel                 matlab.ui.control.Label
        IABSpinner                      matlab.ui.control.Spinner
        CanalBECPanel                   matlab.ui.container.Panel
        CodigoPCMEditField_3Label       matlab.ui.control.Label
        CodigoPCMEditField_3            matlab.ui.control.EditField
        ProbabilidaddeErrorEditField_2Label  matlab.ui.control.Label
        ProbabilidaddeErrorEditField_2  matlab.ui.control.EditField
        CalcularCaracteristicasdeCanalButton  matlab.ui.control.Button
        HAbitssimboloSpinner_2Label     matlab.ui.control.Label
        HAbitssimboloSpinner_2          matlab.ui.control.Spinner
        HABSpinner_2Label               matlab.ui.control.Label
        HABSpinner_2                    matlab.ui.control.Spinner
        IABSpinner_2Label               matlab.ui.control.Label
        IABSpinner_2                    matlab.ui.control.Spinner
        CodificacindeFanoTab            matlab.ui.container.Tab
        CodificacindeFanoPanel          matlab.ui.container.Panel
        MensajeaCodificarEditFieldLabel  matlab.ui.control.Label
        MensajeaCodificarEditField      matlab.ui.control.EditField
        CodificacindeFanoEditFieldLabel  matlab.ui.control.Label
        CodificacindeFanoEditField      matlab.ui.control.EditField
        CodificarButton                 matlab.ui.control.Button
        EntropiaSpinner_4Label          matlab.ui.control.Label
        EntropiaSpinner_4               matlab.ui.control.Spinner
        LongitudMediaSpinnerLabel       matlab.ui.control.Label
        LongitudMediaSpinner            matlab.ui.control.Spinner
        EficienciaSpinnerLabel          matlab.ui.control.Label
        EficienciaSpinner               matlab.ui.control.Spinner
        CaracterizacinLabel             matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        CodificacindeHuffmanTab         matlab.ui.container.Tab
        CodificacindeHuffmanPanel       matlab.ui.container.Panel
        MensajeaCodificarEditField_2Label  matlab.ui.control.Label
        MensajeaCodificarEditField_2    matlab.ui.control.EditField
        CodificacindeFanoEditField_2Label  matlab.ui.control.Label
        CodificacindeFanoEditField_2    matlab.ui.control.EditField
        CodificarButton_2               matlab.ui.control.Button
        EntropiaSpinner_5Label          matlab.ui.control.Label
        EntropiaSpinner_5               matlab.ui.control.Spinner
        LongitudMediaSpinner_2Label     matlab.ui.control.Label
        LongitudMediaSpinner_2          matlab.ui.control.Spinner
        EficienciaSpinner_2Label        matlab.ui.control.Label
        EficienciaSpinner_2             matlab.ui.control.Spinner
        CaracterizacinLabel_2           matlab.ui.control.Label
        UITable4_2                      matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EjecutarButtonPushed(app, event)
            app.TabGroup.Visible = 'off';
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % Nodes Configurate
            node = app.Tree.SelectedNodes.Text
            % State Panels
            homepage = [app.Image.Visible];
            optionMenu = ["🏠 Pagina Principal" "Parametrización por SNRq"];
            optionMenu(2)
            strcmp(node, optionMenu(2))
            menuStatus = [app.PCMPAMScriptPanel.Visible];
            
            if strcmp(node, optionMenu(1)) == 1
                app.PCMPAMScriptPanel.Visible="off";
            end
            if strcmp(node, optionMenu(2)) == 1
               app.PCMPAMScriptPanel.Visible="on";
%                 pcm_pam_script
            end
        end

        % Button pushed function: 
        % ObtenerNivelesyBitsporSimboloButton
        function ObtenerNivelesyBitsporSimboloButtonPushed(app, event)

             [n L] = calculateSNR(app.SNRqdBSpinner.Value);
             app.Label.Text = strcat('L = ',int2str(L),' Niveles')
             app.Label_2.Text = strcat('n = ',int2str(n),' [bits/PCM word]')
          
        end

        % Button pushed function: CalcularSealdeVozyParametrosButton
        function CalcularSealdeVozyParametrosButtonPushed(app, event)
            
            A1 = app.A1Spinner.Value;
            A2 = app.A2Spinner.Value;
            A3 = app.A3Spinner.Value;
            A4 = app.A4Spinner.Value;
            
            f1 = app.f1Spinner.Value;
            f2 = app.f2Spinner.Value;
            f3 = app.f3Spinner.Value;
            f4 = app.f4Spinner.Value;
            
            [n L] = calculateSNR(app.SNRqdBSpinner.Value);
            app.Label.Text = strcat('L = ',int2str(L),' Niveles');
            app.Label_2.Text = strcat('n = ',int2str(n),' [bits/PCM word]');
            
            [t F R_b B_pcm R_s B_pam quatizedSignal tb ...
                f P1 t_pam PAM tagsDec valoresCuatificacion ... 
                tagsBin trama pcm_r f_pam P1_pam trama_pcm] = signalVoice(A1,A2,A3,A4,f1,f2,f3,f4,n,L)
            plotSignalVoice = plot(app.UIAxes,t, F);
            plotSignalVoice.LineWidth = 1.5;
            plotSignalVoice = 'red';
           
            
            app.RbbitssegundoSpinner.Value = R_b;
            app.BpcmHzSpinner.Value = B_pcm;
            app.MPAMRsbaudiosSpinner.Value = R_s;
            app.BpamHzSpinner.Value = B_pam;


            % Coded Signal

            plot(app.UIAxes2,t,F,t,quatizedSignal, 'LineWidth',1.5);
            app.UIAxes2.YTick = valoresCuatificacion;
            app.UIAxes2.YTickLabel = tagsBin;
            tags={};
            for i=1:1:length(tagsBin) 
                tags{i} = num2str(tagsBin{i});
            end
            
            a = [flip(num2cell(valoresCuatificacion')) flip(tags')];
            app.UITable.Data = a;
            
            % Digital Data
            
            plotDigital = plot(app.UIAxes2_2,tb,trama);
                plotDigital.LineWidth = 1.5;
                plotDigital.Color='#0D00EB';
                app.UIAxes2_2.YLim = [-2 2];
                app.UIAxes2_2.XLim = [0 tb(1000)];
                
                valTrama = ''
                for i=1:1:length(pcm_r)
                    valTrama = strcat(valTrama,pcm_r(i,:),'-');
                end
             app.TramaGeneradaEditField.Value = valTrama
            
            
            % PCM Signal - Frequency Domain
            plotDigitalFrequency = plot(app.UIAxes3,f,P1);
                app.UIAxes3.XLim = [0 36e3];
                app.UIAxes3.YLim = [0 0.5];
                
            %% Graphics PAM
            
            %PAM signal
            
            plotPAM = plot(app.UIAxes4,f_pam, P1_pam,'LineWidth',1.5);
            total = length(trama_pcm)
            Pceros = length(find(trama_pcm==0))/total;
            Punos = length(find(trama_pcm==1))/total;
            entropia = length(find(trama_pcm==0))*Pceros*log2(1/Pceros)+...
                length(find(trama_pcm==1))*Punos*log2(1/Punos);
            app.EntropiaBitsCaracterSpinner.Value = entropia;
            entropia_nats = entropia*log(2);
            app.EntropianatsSpinner.Value = entropia_nats;
            
        end

        % Button pushed function: CalcularParmetrosButton
        function CalcularParmetrosButtonPushed(app, event)
            fm = app.FrecuenciafmEditField.Value;
            A1 =app.VppEditField.Value/2;
            L = 1/(2*app.ErrordeCuantizacinEditField.Value);
            if L==0 || L < 0
                alert('Fuera del Rango Establecido')
            elseif L > 0 && L<=1
                L=1;
            elseif L>1 && L<=2
                L=2;
            elseif L>2 && L<=4
                L=4;
            elseif L>4 && L<=8
                L=8;
            elseif L>8 && L<=16
                L=16;
            elseif L>16 && L<=32
                L=32;
            elseif L>32 && L<=64
                L=64;
            elseif L>64 && L<=128
                L=128;
            elseif L>128 && L<=256
                L=256;
            end
            n = log(L)/log(2);
            M = app.MPAMEditField.Value;
            
            [t F R_b B_pcm R_s B_pam quatizedSignal tb ...
                f P1 t_pam PAM tagsDec valoresCuatificacion ... 
                tagsBin trama pcm_r f_pam P1_pam trama_pcm] = signalVoice(A1,0,0,0,fm,0,0,0,n,L);
            
            app.RbbitssegundoSpinner_2.Value = R_b;
            app.BpcmHzSpinner_2.Value = B_pcm;
            app.MPAMRsbaudiosSpinner_2.Value = R_s;
            app.BpamHzSpinner_2.Value = B_pam;
            
            total = length(trama_pcm);
            Pceros = length(find(trama_pcm==0))/total;
            Punos = length(find(trama_pcm==1))/total;
            entropia = length(find(trama_pcm==0))*Pceros*log2(1/Pceros)+...
                length(find(trama_pcm==1))*Punos*log2(1/Punos);
            app.EntropiaBitsCaracterSpinner_2.Value = entropia;
            entropia_nats = entropia*log(2);
            app.EntropianatsSpinner_2.Value = entropia_nats;
            
            % Coded Signal

            plot(app.UIAxes5,t,F,t,quatizedSignal, 'LineWidth',1.5);
            app.UIAxes5.YTick = valoresCuatificacion;
            app.UIAxes5.YTickLabel = tagsBin;
            tags={};
            for i=1:1:length(tagsBin) 
                tags{i} = num2str(tagsBin{i});
            end
            
            a = [flip(num2cell(valoresCuatificacion')) flip(tags')];
            app.UITable2.Data = a;
            
             valTramaPCM = '';
             tramaCompleta = '';
             for i=1:1:length(pcm_r)
                    tramaCompleta = strcat(tramaCompleta,pcm_r(i,:));
                    valTramaPCM = strcat(valTramaPCM,pcm_r(i,:),'-');
             end
             k = log(M)/log(2);
             
             for i=1:length(tramaCompleta)
                 if mod(length(tramaCompleta),k)==0
                     i = length(tramaCompleta);
                 else
                     tramaCompleta(end) = []
                 end
             end
             
             pam_r = reshape(tramaCompleta,k,[])';
             valTramaPAM = '';
             for i=1:1:length(pcm_r)
                    valTramaPAM = strcat(valTramaPAM,pam_r(i,:),'-');
             end
             
             app.TramaPCMEditField_2.Value = valTramaPCM;
             app.TramaPAMEditField.Value = valTramaPAM;
             app.nbitsPCMwordSpinner.Value = n;
             app.kbitssimbolosSpinner.Value = k;
        end

        % Button pushed function: CalcularEntropiaButton
        function CalcularEntropiaButtonPushed(app, event)
            disp(app.INSERTESUMENSAJETextArea.Value)
            mensaje = app.INSERTESUMENSAJETextArea.Value;
%             
             [entropia letras repeticiones entropia_ll ll]=entropiaMensaje(mensaje);
           app.EntropiaSpinner.Value = entropia;
           app.TotaldeLetrasSpinner.Value = sum(repeticiones)
            
            tags={};
            for i=1:1:length(letras) 
                tags{i} = num2str(letras{i});
            end
            
            a = [num2cell(repeticiones') tags'];
            app.UITable3.Data = a;
            app.TramadeBitsEditField.Value =ll;
            app.EntropiaSpinner_2.Value = entropia_ll;
        end

        % Button pushed function: CalculodePotenciasButton
        function CalculodePotenciasButtonPushed(app, event)
            mu = app.MediaSpinner.Value;
            desviacion = app.DesviacinEstandarSpinner.Value;
            [x y_norm potenciaDC potenciaAC potenciaTotal entropia]=potenciaRuido(mu,desviacion);
            app.PotenciaContinuaSpinner.Value = potenciaDC;
            app.PotenciaAltenaSpinner.Value =potenciaAC;
            app.PotenciaTotalSpinner.Value = potenciaTotal;
            app.EntropiaSpinner_3.Value = entropia;
            
            plot(app.UIAxes6,x,y_norm);
        end

        % Button pushed function: CalcularCapacidaddelCanalButton
        function CalcularCapacidaddelCanalButtonPushed(app, event)
            B=app.AnchodeBandaHzSpinner.Value;
            SNR=10^(app.SNRdBSpinner.Value/10);
            Capacidad=B*log2(1+SNR);
            app.CapacidadbitssegundoSpinner.Value = Capacidad;
        end

        % Button pushed function: CalcularParmetrosdeAnlisisButton
        function CalcularParmetrosdeAnlisisButtonPushed(app, event)
            Ptx =app.PotenciaTXWSpinner.Value;
            alpha=app.CoeficientedeAtenuacindBmSpinner.Value;
            d1=app.DistanciadeLineadeTransmisind1mSpinner.Value;
            distancia=app.DistanciaentreAntenasDmSpinner.Value;
            Gtx=app.GananciadeTransmisinGTXdBdSpinner.Value;
            fc=app.FrecuenciaPortadorafcHzSpinner.Value;
            Grx=app.GananciadeRecepcinGRXdBiSpinner.Value;
            d2=app.DistanciadeLineadeTransmisind2mSpinner.Value;
            sensitividad=app.SensitividadReceptivadBSpinner.Value;
            
            Gtxd = Gtx+2.15;
            
            PIRE = 10*log10(Ptx/(1e-3)) + Gtxd - (d1)*(alpha);
            coef = (3e8)/(fc)
            Prx = PIRE + 20*log10(coef) + Grx - 50*alpha;
            
            app.PIREdBmSpinner.Value = PIRE;
            app.PotenciadeRecepcindBmSpinner.Value = Prx;
            
            if Prx < sensitividad
                app.ExisteEnlaceLabel.Text = 'No existe enlace Prx < Sensitividad'
                app.CalidadLabel.Text = 'La calidad Seria Totalmente Mala'
            else
                app.ExisteEnlaceLabel.Text = 'Existe enlace Prx > Sensitividad'
                if (Prx - sensitividad) < 3
                    app.CalidadLabel.Text = 'La calidad es Mala | Prx - Sensitividad < 3dB'
                elseif (Prx - sensitividad) >= 3 && (Prx - sensitividad) < 3
                    app.CalidadLabel.Text = 'La calidad es Promedio | Prx - Sensitividad mayor a 3dB pero menor a 9dB'
                elseif (Prx - sensitividad) >= 9
                    app.CalidadLabel.Text = 'La calidad es Excelente | Prx - Sensitividad > 9dB'
                end
            end
        end

        % Button pushed function: GraficarTramayEntropiaButton
        function GraficarTramayEntropiaButtonPushed(app, event)
            
            
            bits = app.CodigoPCMEditField.Value;
            A = app.AmplitudEditField.Value; 
            A = (str2num(A))/2;
            B = app.AnchodeBandadelCanalEditField.Value;
            B = str2num(B);
            pcm = num2str(bits);
            d = [];
            for i = 1:length(pcm)
                if pcm(i) == '1'
                    d = [d , 1];
                elseif pcm(i) == '0'
                    d = [d, -1];
                end
            end
            N = length(d);                       
            n_var = [0:0.1:10];            
            S = (A/2)^2;                    
            
            SNR_db = abs(10*log10(S./n_var));    
            C = B*log2(1+SNR_db);
            
            Ns = 50;                           
            t = ([1:Ns]-0.5)/Ns;               
            p = ones(size(t));                 
            X_aux(1:Ns:(Ns*(N-1)+1)) = d.*A;      
            X = conv(X_aux,p);                  
            sigma_cuadrado = 0.1;               
            ruido = sqrt(sigma_cuadrado)*randn(size(X));    
            Y = X + ruido;                                
            x_axis = [1:length(Y)]/Ns;                  
            
            plot(app.UIAxes7,x_axis,Y,x_axis,X);
            plot(app.UIAxes8,SNR_db,C);
        end

        % Button pushed function: 
        % CalcularCaracteristicasdeCanalButton_2
        function CalcularCaracteristicasdeCanalButton_2Pushed(app, event)
            bits = app.CodigoPCMEditField_2.Value;
            pcm = num2str(bits);
            d = [];
            cont_unos = 0;
            cont_ceros = 0;
            
            for i = 1:length(pcm)
                if pcm(i) == '1'
                    d = [d , 1];
                    cont_unos = cont_unos + 1 ;
                elseif pcm(i) == '0'
                    d = [d, -1];
                    cont_ceros = cont_ceros + 1;
                end
            end
            N = length(d);
            q = app.ProbabilidaddeErrorEditField.Value;
            q = str2double(q);
            % Matriz de probabilidad del canal BSC
            P = [1-q   q ; 
                 q    1-q];
            
            % Probabilidad de ocurrencia de unos y ceros
            P_a1 = cont_unos/N;
            P_a0 = cont_ceros/N;
            
            % Probabilidades de salida 
            
            P_b0 = P(1,1)*P_a0 + P(2,1)*P_a1;
            P_b1 = 1 - P_b0;
            
            % Probabilidades de retorno
            
            P_a0_b0 = (P(1,1)*P_a0)/P_b0;
            P_a1_b0 = 1 - P_a0_b0;
            
            P_a1_b1 = (P(2,2)*P_a1)/P_b1;
            P_a0_b1 = 1 - P_a1_b1;
            
            % Cantidad de informacion en la entrada H(A)
            
            H_A = cont_unos*P_a1 * log2(1/P_a1) + cont_ceros*P_a0 * log2(1/P_a0);
            app.HAbitssimboloSpinner.Value = H_A;
            
            % Cantidad de informacion a posterior H(A|bj)
            
            %P(a=0|b=0)*log(1/P(a=0|b=0)) + P(a=1|b=0)*log(1/P(a=1|b=0));
            
            H_A_b0 = P_a0_b0*log2(1/P_a0_b0) + P_a1_b0*log2(1/P_a1_b0);
            
            % P(a=0|b=1)*log(1/P(a=0|b=1)) + P(a=1|b=1)*log(1/P(a=1|b=1));
            
            H_A_b1 = P_a1_b1*log2(1/P_a1_b1) + P_a0_b1*log2(1/P_a0_b1);
            
            % Se calcula H(A|B)
            
            H_A_B = P_b0 * H_A_b0 + P_b1 * H_A_b1;
         
            app.HABSpinner.Value = H_A_B
            
            %La informacion mutua es: I(A;B) = H(A) - H(A|B)
            I_A_B = H_A - H_A_B;
            
            app.IABSpinner.Value = I_A_B;

        end

        % Button pushed function: 
        % CalcularCaracteristicasdeCanalButton
        function CalcularCaracteristicasdeCanalButtonPushed(app, event)
            bits = app.CodigoPCMEditField.Value;
            pcm = num2str(bits);
            d = [];
            cont_unos = 0;
            cont_ceros = 0;
            cont_erasure = 0;
            for i = 1:length(pcm)
                if pcm(i) == '1'
                    d = [d , 1];
                    cont_unos = cont_unos + 1 ;
                elseif pcm(i) == '0'
                    d = [d, -1];
                    cont_ceros = cont_ceros + 1;
                elseif pcm(i) == '?'
                    d = [d, '?'];
                    cont_erasure = cont_erasure + 1;
                end
            end
            
            N = length(d);
            q = app.ProbabilidaddeErrorEditField_2.Value;
            q = str2double(q);
            
            
            % Matriz de probabilidad del canal BSC
            P = [1-q   q    0 ; 
                 0     q    1-q];
            
            % Probabilidad de ocurrencia de unos y ceros y erasure
            
            P_a1 = cont_unos/N;
            P_a0 = cont_ceros/N;
            P_aer = cont_erasure/N;
            
            % Probabilidades de salida 
            
            P_b0 = (1 - q)*P_a0;
            P_b1 = (1 - q)*P_a1;
            P_berasure = P(1,2)*P_a0 + P(2,2)*P_a1;
            
            % Probabilidades de retorno
            
            P_a0_b0 = (P(1,1)*P_a0)/P_b0;
            %P_a1_b0 = 1 - P_a0_b0;
            
            P_a1_b1 = (P(2,3)*P_a1)/P_b1;
            %P_a0_b1 = 1 - P_a1_b1;
            
            P_a0_berasure = (P(1,2)*P_a0)/P_berasure;
            P_a1_berasure = (P(2,2)*P_a1)/P_berasure;
            
            % Cantidad de informacion en la entrada H(A)
            
            H_A = cont_unos*P_a1 * log2(1/P_a1) + cont_ceros*P_a0 * log2(1/P_a0);
            
            app.HAbitssimboloSpinner_2.Value = H_A;
            
            % Cantidad de informacion a posterior H(A|bj)
            
            % P(a=0|b=0)*log(1/P(a=0|b=0)) + P(a=1|b=0)*log(1/P(a=1|b=0));
            
            H_A_b0 = P_a0_b0*log2(1/P_a0_b0);
            
            % P(a=0|b=1)*log(1/P(a=0|b=1)) + P(a=1|b=1)*log(1/P(a=1|b=1));
            
            H_A_b1 = P_a1_b1*log2(1/P_a1_b1);
            
            H_A_berasure = P_a0_berasure*log2(1/P_a0_berasure) + P_a1_berasure*log2(1/P_a1_berasure);
            
            % Se calcula H(A|B)
            
            H_A_B = P_b0*H_A_b0 + P_b1*H_A_b1 + P_berasure*H_A_berasure;
            
            app.HABSpinner_2.Value = H_A_B;
            %La informacion mutua es: I(A;B) = H(A) - H(A|B)
            
            I_A_B = H_A - H_A_B;
            
            app.IABSpinner_2.Value = I_A_B;
        end

        % Button pushed function: CodificarButton
        function CodificarButtonPushed(app, event)
            
            mensaje = app.MensajeaCodificarEditField.Value;
            [L tal eficiencia Resultado fano]=calculateFano(mensaje);
            
            app.CodificacindeFanoEditField.Value = fano;
            app.UITable4.Data = Resultado;
            app.LongitudMediaSpinner.Value = L;
            app.EntropiaSpinner_4.Value = tal;
            app.EficienciaSpinner.Value = eficiencia;
            

        end

        % Button pushed function: CodificarButton_2
        function CodificarButton_2Pushed(app, event)
            mensaje = app.MensajeaCodificarEditField_2.Value;
            [L tal eficiencia Resultado huffman]=calculateHuffman(mensaje);
            
            app.CodificacindeFanoEditField_2.Value = huffman;
            app.UITable4_2.Data = Resultado;
            app.LongitudMediaSpinner_2.Value = L;
            app.EntropiaSpinner_5.Value = tal;
            app.EficienciaSpinner_2.Value = eficiencia;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PginaPrincipalTab = uitab(app.TabGroup);
            app.PginaPrincipalTab.Title = '🏠 Página Principal';
            app.GridLayout8 = uigridlayout(app.PginaPrincipalTab);
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = 'tics.png';
            app.Image6 = uiimage(app.GridLayout8);
            app.Image6.ImageSource = 'bit.gif';
            app.GuiadeUsuarioTab = uitab(app.TabGroup);
            app.GuiadeUsuarioTab.Title = '📑 Guia de Usuario';
            app.TextArea = uitextarea(app.GuiadeUsuarioTab);
            app.TextArea.Value = {'######################################################'; '##### TEORIA DE LA INFORMACION Y LA CODIFICACION #####'; '######################################################'; ''; 'Grupo 3: Nestor Castillo, Soledad Villegas'; ''; ''; '*******  DETALLE'; ''; 'En el siguiente Software se mostrara un Lista de Scripts que resolveran lo aprendido dentro de los capitulo I, III y III de '; 'la materia de Teoria de la Información y la Codificación.'; ''; '*******  LISTA DE SCRIPTS'; '1️⃣ Caracterización PCM-PAM por SNRq'; '2️⃣ Caracterización PCM-PAM con Exceso de Vpp'; '3️⃣ Entropia e Información de un Mensaje'; '4️⃣ Calidad y Calculo de un Enlace'; '5️⃣ Potencia e Entropia del Ruido'; '6️⃣ Información Mutua de Canales BSC & BEC'; '7️⃣ Codificación de Fano'; '8️⃣ Codificación de Huffman'; ''; ''};
            app.Image7 = uiimage(app.GuiadeUsuarioTab);
            app.Image7.ImageSource = 'GUIA-DE-USUARIO.gif';
            app.EJEMPLODEUSOLabel = uilabel(app.GuiadeUsuarioTab);
            app.EJEMPLODEUSOLabel.Text = 'EJEMPLO DE USO';
            app.CaracterizacinPCMPAMporSNRqTab = uitab(app.TabGroup);
            app.CaracterizacinPCMPAMporSNRqTab.Title = '1️⃣ Caracterización PCM-PAM por SNRq';
            app.GridLayout9 = uigridlayout(app.CaracterizacinPCMPAMporSNRqTab);
            app.PCMPAMScriptPanel = uipanel(app.GridLayout9);
            app.PCMPAMScriptPanel.Title = 'PCM - PAM Script';
            app.GridLayout10 = uigridlayout(app.PCMPAMScriptPanel);
            app.CalculodeNivelesPanel = uipanel(app.GridLayout10);
            app.CalculodeNivelesPanel.Title = 'Calculo de Niveles';
            app.ObtenerNivelesyBitsporSimboloButton = uibutton(app.CalculodeNivelesPanel, 'push');
            app.ObtenerNivelesyBitsporSimboloButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenerNivelesyBitsporSimboloButtonPushed, true);
            app.ObtenerNivelesyBitsporSimboloButton.Text = 'Obtener Niveles y Bits por Simbolo';
            app.Label = uilabel(app.CalculodeNivelesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.CalculodeNivelesPanel);
            app.Label_2.Text = {''; ''};
            app.SNRqdBSpinnerLabel = uilabel(app.CalculodeNivelesPanel);
            app.SNRqdBSpinnerLabel.Text = 'SNRq (dB)';
            app.SNRqdBSpinner = uispinner(app.CalculodeNivelesPanel);
            app.SNRqdBSpinner.Value = 35;
            app.InserteelSNRdecuantizacinLabel = uilabel(app.CalculodeNivelesPanel);
            app.InserteelSNRdecuantizacinLabel.Text = 'Inserte el SNR de cuantización';
            app.SealdeVozPanel = uipanel(app.GridLayout10);
            app.SealdeVozPanel.Title = 'Señal de Voz';
            app.A1SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A1SpinnerLabel.Text = 'A1';
            app.A1Spinner = uispinner(app.SealdeVozPanel);
            app.A1Spinner.Value = 3;
            app.A2SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A2SpinnerLabel.Text = 'A2';
            app.A2Spinner = uispinner(app.SealdeVozPanel);
            app.A2Spinner.Value = 5;
            app.A3SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A3SpinnerLabel.Text = 'A3';
            app.A3Spinner = uispinner(app.SealdeVozPanel);
            app.A3Spinner.Value = 1;
            app.A4SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.A4SpinnerLabel.Text = 'A4';
            app.A4Spinner = uispinner(app.SealdeVozPanel);
            app.A4Spinner.Value = 1.5;
            app.CalcularSealdeVozyParametrosButton = uibutton(app.SealdeVozPanel, 'push');
            app.CalcularSealdeVozyParametrosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularSealdeVozyParametrosButtonPushed, true);
            app.CalcularSealdeVozyParametrosButton.Text = 'Calcular Señal de Voz y Parametros';
            app.f1SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f1SpinnerLabel.Text = 'f1';
            app.f1Spinner = uispinner(app.SealdeVozPanel);
            app.f1Spinner.Value = 4000;
            app.f2SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f2SpinnerLabel.Text = 'f2';
            app.f2Spinner = uispinner(app.SealdeVozPanel);
            app.f2Spinner.Value = 3300;
            app.f3SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f3SpinnerLabel.Text = 'f3';
            app.f3Spinner = uispinner(app.SealdeVozPanel);
            app.f3Spinner.Value = 2400;
            app.f4SpinnerLabel = uilabel(app.SealdeVozPanel);
            app.f4SpinnerLabel.Text = 'f4';
            app.f4Spinner = uispinner(app.SealdeVozPanel);
            app.f4Spinner.Value = 1500;
            app.ParmetrosCaracteristicosPanel = uipanel(app.GridLayout10);
            app.ParmetrosCaracteristicosPanel.Title = 'Parámetros Caracteristicos';
            app.RbbitssegundoSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.RbbitssegundoSpinnerLabel.Text = 'Rb [bits/segundo]';
            app.RbbitssegundoSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.BpcmHzSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.BpcmHzSpinnerLabel.Text = 'Bpcm [Hz]';
            app.BpcmHzSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.MPAMRsbaudiosSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.MPAMRsbaudiosSpinnerLabel.Text = 'M-PAM Rs [baudios]';
            app.MPAMRsbaudiosSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.BpamHzSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.BpamHzSpinnerLabel.Text = 'Bpam [Hz]';
            app.BpamHzSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.EntropiaBitsCaracterSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.EntropiaBitsCaracterSpinnerLabel.Text = 'Entropia [Bits/Caracter]';
            app.EntropiaBitsCaracterSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.EntropianatsSpinnerLabel = uilabel(app.ParmetrosCaracteristicosPanel);
            app.EntropianatsSpinnerLabel.Text = 'Entropia [nats]';
            app.EntropianatsSpinner = uispinner(app.ParmetrosCaracteristicosPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.SealdeVozAnalgicaTab = uitab(app.TabGroup2);
            app.SealdeVozAnalgicaTab.Title = 'Señal de Voz Analógica';
            app.GridLayout4 = uigridlayout(app.SealdeVozAnalgicaTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Señal de Voz')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'Nivel de Voltaje [V]')
            app.SealCuantificadaTab = uitab(app.TabGroup2);
            app.SealCuantificadaTab.Title = 'Señal Cuantificada';
            app.GridLayout5 = uigridlayout(app.SealCuantificadaTab);
            app.UIAxes2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2, 'Señal Cuantificada')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'Nivel de Voltage [V]')
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'Voltaje'; 'Simbolo'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.SealDigitalTab = uitab(app.TabGroup2);
            app.SealDigitalTab.Title = 'Señal Digital';
            app.GridLayout6 = uigridlayout(app.SealDigitalTab);
            app.UIAxes2_2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2_2, 'Señal Codificada / Señal Digital')
            xlabel(app.UIAxes2_2, 't [s]')
            ylabel(app.UIAxes2_2, 'Valor de Bit')
            app.TramaGeneradaEditFieldLabel = uilabel(app.GridLayout6);
            app.TramaGeneradaEditFieldLabel.Text = 'Trama Generada';
            app.TramaGeneradaEditField = uieditfield(app.GridLayout6, 'text');
            app.DominioFrecuencialTab = uitab(app.TabGroup2);
            app.DominioFrecuencialTab.Title = 'Dominio Frecuencial';
            app.GridLayout7 = uigridlayout(app.DominioFrecuencialTab);
            app.UIAxes3 = uiaxes(app.GridLayout7);
            title(app.UIAxes3, 'Señal PCM - Dominio de la Frecuencia')
            xlabel(app.UIAxes3, 'Voltage [V]')
            ylabel(app.UIAxes3, 'frecuencia [Hz]')
            app.UIAxes4 = uiaxes(app.GridLayout7);
            title(app.UIAxes4, 'Señal PAM - Dominio de la Frecuencia')
            xlabel(app.UIAxes4, 'Frecuencia [Hz]')
            ylabel(app.UIAxes4, 'Voltage [V]')
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'Bit_Depth.png';
            app.CaracterizacinPCMPAMconExcesodeVppTab = uitab(app.TabGroup);
            app.CaracterizacinPCMPAMconExcesodeVppTab.Title = '2️⃣ Caracterización PCM-PAM con Exceso de Vpp';
            app.CaracterizacinPCMPAMconExcesodeVPPPanel = uipanel(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.CaracterizacinPCMPAMconExcesodeVPPPanel.Title = 'Caracterización PCM-PAM con Exceso de VPP';
            app.DatosdeEntradaPanel = uipanel(app.CaracterizacinPCMPAMconExcesodeVPPPanel);
            app.DatosdeEntradaPanel.Title = 'Datos de Entrada';
            app.FrecuenciafmEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.FrecuenciafmEditFieldLabel.Text = 'Frecuencia fm:';
            app.FrecuenciafmEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.FrecuenciafmEditField.Value = 1000;
            app.VppEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.VppEditFieldLabel.Text = 'Vpp';
            app.VppEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.VppEditField.Value = 1;
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.FrecuenciadeMuestreoEditField.Value = 10000;
            app.ErrordeCuantizacinEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.ErrordeCuantizacinEditFieldLabel.Text = 'Error de Cuantización';
            app.ErrordeCuantizacinEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.ErrordeCuantizacinEditField.Value = 0.02;
            app.MPAMEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.MPAMEditFieldLabel.Text = 'M - PAM';
            app.MPAMEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.MPAMEditField.Value = 8;
            app.CalcularParmetrosButton = uibutton(app.DatosdeEntradaPanel, 'push');
            app.CalcularParmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularParmetrosButtonPushed, true);
            app.CalcularParmetrosButton.Text = 'Calcular Parámetros';
            app.ParmetrosCaracteristicosPanel_2 = uipanel(app.CaracterizacinPCMPAMconExcesodeVPPPanel);
            app.ParmetrosCaracteristicosPanel_2.Title = 'Parámetros Caracteristicos';
            app.RbbitssegundoSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.RbbitssegundoSpinner_2Label.Text = 'Rb [bits/segundo]';
            app.RbbitssegundoSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.BpcmHzSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.BpcmHzSpinner_2Label.Text = 'Bpcm [Hz]';
            app.BpcmHzSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.MPAMRsbaudiosSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.MPAMRsbaudiosSpinner_2Label.Text = 'M-PAM Rs [baudios]';
            app.MPAMRsbaudiosSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.BpamHzSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.BpamHzSpinner_2Label.Text = 'Bpam [Hz]';
            app.BpamHzSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.EntropiaBitsCaracterSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.EntropiaBitsCaracterSpinner_2Label.Text = 'Entropia [Bits/Caracter]';
            app.EntropiaBitsCaracterSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.EntropianatsSpinner_2Label = uilabel(app.ParmetrosCaracteristicosPanel_2);
            app.EntropianatsSpinner_2Label.Text = 'Entropia [nats]';
            app.EntropianatsSpinner_2 = uispinner(app.ParmetrosCaracteristicosPanel_2);
            app.UIAxes5 = uiaxes(app.CaracterizacinPCMPAMconExcesodeVppTab);
            title(app.UIAxes5, 'Señal Cuantificada')
            xlabel(app.UIAxes5, 't [s]')
            ylabel(app.UIAxes5, 'Nivel de Voltaje [v]')
            app.UITable2 = uitable(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.UITable2.ColumnName = {'Voltage'; 'Simbolo'};
            app.UITable2.RowName = {};
            app.TramaPCMEditField_2Label = uilabel(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.TramaPCMEditField_2Label.Text = 'Trama PCM';
            app.TramaPCMEditField_2 = uieditfield(app.CaracterizacinPCMPAMconExcesodeVppTab, 'text');
            app.TramaPAMEditFieldLabel = uilabel(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.TramaPAMEditFieldLabel.Text = 'Trama PAM';
            app.TramaPAMEditField = uieditfield(app.CaracterizacinPCMPAMconExcesodeVppTab, 'text');
            app.nbitsPCMwordSpinnerLabel = uilabel(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.nbitsPCMwordSpinnerLabel.Text = 'n [bits/PCM word]';
            app.nbitsPCMwordSpinner = uispinner(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.kbitssimbolosSpinnerLabel = uilabel(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.kbitssimbolosSpinnerLabel.Text = 'k [bits/simbolos]';
            app.kbitssimbolosSpinner = uispinner(app.CaracterizacinPCMPAMconExcesodeVppTab);
            app.EntropiaeInformacindeunMensajeTab = uitab(app.TabGroup);
            app.EntropiaeInformacindeunMensajeTab.Title = '3️⃣ Entropia e Información de un Mensaje';
            app.GridLayout12 = uigridlayout(app.EntropiaeInformacindeunMensajeTab);
            app.DatosdeEntradaPanel_2 = uipanel(app.GridLayout12);
            app.DatosdeEntradaPanel_2.Title = 'Datos de Entrada';
            app.INSERTESUMENSAJETextAreaLabel = uilabel(app.DatosdeEntradaPanel_2);
            app.INSERTESUMENSAJETextAreaLabel.Text = 'INSERTE SU MENSAJE';
            app.INSERTESUMENSAJETextArea = uitextarea(app.DatosdeEntradaPanel_2);
            app.INSERTESUMENSAJETextArea.Value = {'INGENIERIA ELECTRONICA EN TELECOMUNICACIONES'};
            app.CalcularEntropiaButton = uibutton(app.DatosdeEntradaPanel_2, 'push');
            app.CalcularEntropiaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularEntropiaButtonPushed, true);
            app.CalcularEntropiaButton.Text = 'Calcular Entropia';
            app.ResultadosPanel = uipanel(app.GridLayout12);
            app.ResultadosPanel.Title = 'Resultados';
            app.EntropiaSpinnerLabel = uilabel(app.ResultadosPanel);
            app.EntropiaSpinnerLabel.Text = 'Entropia';
            app.EntropiaSpinner = uispinner(app.ResultadosPanel);
            app.UITable3 = uitable(app.ResultadosPanel);
            app.UITable3.ColumnName = {'Letra'; 'Probabilidad'};
            app.UITable3.RowName = {};
            app.TotaldeLetrasSpinnerLabel = uilabel(app.ResultadosPanel);
            app.TotaldeLetrasSpinnerLabel.Text = 'Total de Letras';
            app.TotaldeLetrasSpinner = uispinner(app.ResultadosPanel);
            app.TransformacinacodigoASCIIPanel = uipanel(app.GridLayout12);
            app.TransformacinacodigoASCIIPanel.Title = 'Transformación a codigo ASCII';
            app.TramadeBitsEditFieldLabel = uilabel(app.TransformacinacodigoASCIIPanel);
            app.TramadeBitsEditFieldLabel.Text = 'Trama de Bits';
            app.TramadeBitsEditField = uieditfield(app.TransformacinacodigoASCIIPanel, 'text');
            app.EntropiaSpinner_2Label = uilabel(app.TransformacinacodigoASCIIPanel);
            app.EntropiaSpinner_2Label.Text = 'Entropia';
            app.EntropiaSpinner_2 = uispinner(app.TransformacinacodigoASCIIPanel);
            app.CalidadyCalculodeunEnlaceTab = uitab(app.TabGroup);
            app.CalidadyCalculodeunEnlaceTab.Title = '4️⃣ Calidad y Calculo de un Enlace';
            app.GridLayout13 = uigridlayout(app.CalidadyCalculodeunEnlaceTab);
            app.Image5 = uiimage(app.GridLayout13);
            app.Image5.ImageSource = 'enlace.png';
            app.DatosdeEntradaPanel_4 = uipanel(app.GridLayout13);
            app.DatosdeEntradaPanel_4.Title = 'Datos de Entrada';
            app.PotenciaTXWSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.PotenciaTXWSpinnerLabel.Text = 'Potencia (TX) [W]';
            app.PotenciaTXWSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.PotenciaTXWSpinner.Value = 25;
            app.CoeficientedeAtenuacindBmSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.CoeficientedeAtenuacindBmSpinnerLabel.Text = 'Coeficiente de Atenuación α [dB/m]';
            app.CoeficientedeAtenuacindBmSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.CoeficientedeAtenuacindBmSpinner.Value = 1.2;
            app.DistanciadeLineadeTransmisind1mSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.DistanciadeLineadeTransmisind1mSpinnerLabel.Text = 'Distancia de Linea de Transmisión d1 [m]';
            app.DistanciadeLineadeTransmisind1mSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.DistanciadeLineadeTransmisind1mSpinner.Value = 60;
            app.DistanciaentreAntenasDmSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.DistanciaentreAntenasDmSpinnerLabel.Text = 'Distancia entre Antenas D [m]';
            app.DistanciaentreAntenasDmSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.DistanciaentreAntenasDmSpinner.Value = 7000;
            app.GananciadeTransmisinGTXdBdSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.GananciadeTransmisinGTXdBdSpinnerLabel.Text = 'Ganancia de Transmisión G(TX) [dBd]';
            app.GananciadeTransmisinGTXdBdSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.GananciadeTransmisinGTXdBdSpinner.Value = 17;
            app.FrecuenciaPortadorafcHzSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.FrecuenciaPortadorafcHzSpinnerLabel.Text = 'Frecuencia Portadora fc [Hz]';
            app.FrecuenciaPortadorafcHzSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.FrecuenciaPortadorafcHzSpinner.Value = 5000000000;
            app.GananciadeRecepcinGRXdBiSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.GananciadeRecepcinGRXdBiSpinnerLabel.Text = 'Ganancia de Recepción G(RX) [dBi]';
            app.GananciadeRecepcinGRXdBiSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.GananciadeRecepcinGRXdBiSpinner.Value = 15;
            app.DistanciadeLineadeTransmisind2mSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.DistanciadeLineadeTransmisind2mSpinnerLabel.Text = 'Distancia de Linea de Transmisión d2 [m]';
            app.DistanciadeLineadeTransmisind2mSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.DistanciadeLineadeTransmisind2mSpinner.Value = 50;
            app.CalcularParmetrosdeAnlisisButton = uibutton(app.DatosdeEntradaPanel_4, 'push');
            app.CalcularParmetrosdeAnlisisButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularParmetrosdeAnlisisButtonPushed, true);
            app.CalcularParmetrosdeAnlisisButton.Text = 'Calcular Parámetros de Análisis';
            app.SensitividadReceptivadBSpinnerLabel = uilabel(app.DatosdeEntradaPanel_4);
            app.SensitividadReceptivadBSpinnerLabel.Text = 'Sensitividad Receptiva [dB]';
            app.SensitividadReceptivadBSpinner = uispinner(app.DatosdeEntradaPanel_4);
            app.SensitividadReceptivadBSpinner.Value = 117;
            app.ResultadosPanel_3 = uipanel(app.GridLayout13);
            app.ResultadosPanel_3.Title = 'Resultados';
            app.PIREdBmSpinnerLabel = uilabel(app.ResultadosPanel_3);
            app.PIREdBmSpinnerLabel.Text = 'PIRE [dBm]';
            app.PIREdBmSpinner = uispinner(app.ResultadosPanel_3);
            app.PotenciadeRecepcindBmSpinnerLabel = uilabel(app.ResultadosPanel_3);
            app.PotenciadeRecepcindBmSpinnerLabel.Text = 'Potencia de Recepción [dBm]';
            app.PotenciadeRecepcindBmSpinner = uispinner(app.ResultadosPanel_3);
            app.ExisteEnlaceLabel = uilabel(app.ResultadosPanel_3);
            app.ExisteEnlaceLabel.Text = 'Existe Enlace?';
            app.CalidadLabel = uilabel(app.ResultadosPanel_3);
            app.CalidadLabel.Text = 'Calidad';
            app.PotenciaeEntropiadelRuidoTab_3 = uitab(app.TabGroup);
            app.PotenciaeEntropiadelRuidoTab_3.Title = '5️⃣ Potencia e Entropia del Ruido';
            app.GridLayout11 = uigridlayout(app.PotenciaeEntropiadelRuidoTab_3);
            app.DatosdeEntradaPanel_3 = uipanel(app.GridLayout11);
            app.DatosdeEntradaPanel_3.Title = 'Datos de Entrada';
            app.MediaSpinnerLabel = uilabel(app.DatosdeEntradaPanel_3);
            app.MediaSpinnerLabel.Text = 'Media';
            app.MediaSpinner = uispinner(app.DatosdeEntradaPanel_3);
            app.MediaSpinner.Value = 2;
            app.DesviacinEstandarSpinnerLabel = uilabel(app.DatosdeEntradaPanel_3);
            app.DesviacinEstandarSpinnerLabel.Text = 'Desviación Estandar';
            app.DesviacinEstandarSpinner = uispinner(app.DatosdeEntradaPanel_3);
            app.DesviacinEstandarSpinner.Value = 3;
            app.CalculodePotenciasButton = uibutton(app.DatosdeEntradaPanel_3, 'push');
            app.CalculodePotenciasButton.ButtonPushedFcn = createCallbackFcn(app, @CalculodePotenciasButtonPushed, true);
            app.CalculodePotenciasButton.Text = 'Calculo de Potencias';
            app.ResultadosPanel_2 = uipanel(app.GridLayout11);
            app.ResultadosPanel_2.Title = 'Resultados';
            app.PotenciaContinuaSpinnerLabel = uilabel(app.ResultadosPanel_2);
            app.PotenciaContinuaSpinnerLabel.Text = 'Potencia Continua';
            app.PotenciaContinuaSpinner = uispinner(app.ResultadosPanel_2);
            app.PotenciaAltenaSpinnerLabel = uilabel(app.ResultadosPanel_2);
            app.PotenciaAltenaSpinnerLabel.Text = 'Potencia Altena';
            app.PotenciaAltenaSpinner = uispinner(app.ResultadosPanel_2);
            app.PotenciaTotalSpinnerLabel = uilabel(app.ResultadosPanel_2);
            app.PotenciaTotalSpinnerLabel.Text = 'Potencia Total';
            app.PotenciaTotalSpinner = uispinner(app.ResultadosPanel_2);
            app.EntropiaSpinner_3Label = uilabel(app.ResultadosPanel_2);
            app.EntropiaSpinner_3Label.Text = 'Entropia';
            app.EntropiaSpinner_3 = uispinner(app.ResultadosPanel_2);
            app.UIAxes6 = uiaxes(app.GridLayout11);
            title(app.UIAxes6, 'Distribución del Ruido')
            xlabel(app.UIAxes6, 'y [Voltios]')
            ylabel(app.UIAxes6, 'f(y)')
            app.Image3 = uiimage(app.GridLayout11);
            app.Image3.ImageSource = 'noise.png';
            app.InformacinMutuadeCanalesBSCBECTab = uitab(app.TabGroup);
            app.InformacinMutuadeCanalesBSCBECTab.Title = '6️⃣ Información Mutua de Canales BSC & BEC';
            app.GridLayout14 = uigridlayout(app.InformacinMutuadeCanalesBSCBECTab);
            app.Image4 = uiimage(app.GridLayout14);
            app.Image4.ImageSource = 'wire-coil-svgrepo-com.svg';
            app.CapacidaddeCanalporShannonPanel = uipanel(app.GridLayout14);
            app.CapacidaddeCanalporShannonPanel.Title = 'Capacidad de Canal por Shannon';
            app.AnchodeBandaHzSpinnerLabel = uilabel(app.CapacidaddeCanalporShannonPanel);
            app.AnchodeBandaHzSpinnerLabel.Text = 'Ancho de Banda [Hz]';
            app.AnchodeBandaHzSpinner = uispinner(app.CapacidaddeCanalporShannonPanel);
            app.AnchodeBandaHzSpinner.Value = 3100;
            app.SNRdBSpinnerLabel = uilabel(app.CapacidaddeCanalporShannonPanel);
            app.SNRdBSpinnerLabel.Text = 'SNR(dB)';
            app.SNRdBSpinner = uispinner(app.CapacidaddeCanalporShannonPanel);
            app.SNRdBSpinner.Value = 30.1;
            app.CalcularCapacidaddelCanalButton = uibutton(app.CapacidaddeCanalporShannonPanel, 'push');
            app.CalcularCapacidaddelCanalButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCapacidaddelCanalButtonPushed, true);
            app.CalcularCapacidaddelCanalButton.Text = 'Calcular Capacidad del Canal';
            app.CapacidadbitssegundoSpinnerLabel = uilabel(app.CapacidaddeCanalporShannonPanel);
            app.CapacidadbitssegundoSpinnerLabel.Text = 'Capacidad [bits/segundo]';
            app.CapacidadbitssegundoSpinner = uispinner(app.CapacidaddeCanalporShannonPanel);
            app.CapacidaddeCanalPanel = uipanel(app.GridLayout14);
            app.CapacidaddeCanalPanel.Title = 'Capacidad de Canal';
            app.CodigoPCMEditFieldLabel = uilabel(app.CapacidaddeCanalPanel);
            app.CodigoPCMEditFieldLabel.Text = 'Codigo PCM';
            app.CodigoPCMEditField = uieditfield(app.CapacidaddeCanalPanel, 'text');
            app.CodigoPCMEditField.Value = '0101011100011';
            app.AmplitudEditFieldLabel = uilabel(app.CapacidaddeCanalPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.CapacidaddeCanalPanel, 'text');
            app.AmplitudEditField.Value = '2';
            app.AnchodeBandadelCanalEditFieldLabel = uilabel(app.CapacidaddeCanalPanel);
            app.AnchodeBandadelCanalEditFieldLabel.Text = 'Ancho de Banda del Canal';
            app.AnchodeBandadelCanalEditField = uieditfield(app.CapacidaddeCanalPanel, 'text');
            app.AnchodeBandadelCanalEditField.Value = '4e3';
            app.UIAxes7 = uiaxes(app.CapacidaddeCanalPanel);
            title(app.UIAxes7, 'Señalización Polar-NRZ añadido con ruido')
            xlabel(app.UIAxes7, 't [s]')
            ylabel(app.UIAxes7, 'Bit Value')
            app.UIAxes8 = uiaxes(app.CapacidaddeCanalPanel);
            title(app.UIAxes8, 'Capacidad del Canal')
            xlabel(app.UIAxes8, 'SNR [dB]')
            ylabel(app.UIAxes8, 'Capacidad del Canal [bps]')
            app.GraficarTramayEntropiaButton = uibutton(app.CapacidaddeCanalPanel, 'push');
            app.GraficarTramayEntropiaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarTramayEntropiaButtonPushed, true);
            app.GraficarTramayEntropiaButton.Text = 'Graficar Trama y Entropia';
            app.CanalBSCPanel = uipanel(app.GridLayout14);
            app.CanalBSCPanel.Title = 'Canal BSC';
            app.CodigoPCMEditField_2Label = uilabel(app.CanalBSCPanel);
            app.CodigoPCMEditField_2Label.Text = 'Codigo PCM';
            app.CodigoPCMEditField_2 = uieditfield(app.CanalBSCPanel, 'text');
            app.CodigoPCMEditField_2.Value = '0010100111010';
            app.ProbabilidaddeErrorEditFieldLabel = uilabel(app.CanalBSCPanel);
            app.ProbabilidaddeErrorEditFieldLabel.Text = 'Probabilidad de Error';
            app.ProbabilidaddeErrorEditField = uieditfield(app.CanalBSCPanel, 'text');
            app.ProbabilidaddeErrorEditField.Value = '0.00001';
            app.CalcularCaracteristicasdeCanalButton_2 = uibutton(app.CanalBSCPanel, 'push');
            app.CalcularCaracteristicasdeCanalButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularCaracteristicasdeCanalButton_2Pushed, true);
            app.CalcularCaracteristicasdeCanalButton_2.Text = 'Calcular Caracteristicas de Canal';
            app.HAbitssimboloSpinnerLabel = uilabel(app.CanalBSCPanel);
            app.HAbitssimboloSpinnerLabel.Text = 'H(A) [bits/simbolo]';
            app.HAbitssimboloSpinner = uispinner(app.CanalBSCPanel);
            app.HABSpinnerLabel = uilabel(app.CanalBSCPanel);
            app.HABSpinnerLabel.Text = 'H(A/B) ';
            app.HABSpinner = uispinner(app.CanalBSCPanel);
            app.IABSpinnerLabel = uilabel(app.CanalBSCPanel);
            app.IABSpinnerLabel.Text = 'I(A;B)';
            app.IABSpinner = uispinner(app.CanalBSCPanel);
            app.CanalBECPanel = uipanel(app.GridLayout14);
            app.CanalBECPanel.Title = 'Canal BEC';
            app.CodigoPCMEditField_3Label = uilabel(app.CanalBECPanel);
            app.CodigoPCMEditField_3Label.Text = 'Codigo PCM';
            app.CodigoPCMEditField_3 = uieditfield(app.CanalBECPanel, 'text');
            app.CodigoPCMEditField_3.Value = '01010?010111??1';
            app.ProbabilidaddeErrorEditField_2Label = uilabel(app.CanalBECPanel);
            app.ProbabilidaddeErrorEditField_2Label.Text = 'Probabilidad de Error';
            app.ProbabilidaddeErrorEditField_2 = uieditfield(app.CanalBECPanel, 'text');
            app.ProbabilidaddeErrorEditField_2.Value = '0.00001';
            app.CalcularCaracteristicasdeCanalButton = uibutton(app.CanalBECPanel, 'push');
            app.CalcularCaracteristicasdeCanalButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCaracteristicasdeCanalButtonPushed, true);
            app.CalcularCaracteristicasdeCanalButton.Text = 'Calcular Caracteristicas de Canal';
            app.HAbitssimboloSpinner_2Label = uilabel(app.CanalBECPanel);
            app.HAbitssimboloSpinner_2Label.Text = 'H(A) [bits/simbolo]';
            app.HAbitssimboloSpinner_2 = uispinner(app.CanalBECPanel);
            app.HABSpinner_2Label = uilabel(app.CanalBECPanel);
            app.HABSpinner_2Label.Text = 'H(A/B) ';
            app.HABSpinner_2 = uispinner(app.CanalBECPanel);
            app.IABSpinner_2Label = uilabel(app.CanalBECPanel);
            app.IABSpinner_2Label.Text = 'I(A;B)';
            app.IABSpinner_2 = uispinner(app.CanalBECPanel);
            app.CodificacindeFanoTab = uitab(app.TabGroup);
            app.CodificacindeFanoTab.Title = '7️⃣ Codificación de Fano';
            app.CodificacindeFanoPanel = uipanel(app.CodificacindeFanoTab);
            app.CodificacindeFanoPanel.Title = 'Codificación de Fano';
            app.MensajeaCodificarEditFieldLabel = uilabel(app.CodificacindeFanoPanel);
            app.MensajeaCodificarEditFieldLabel.Text = 'Mensaje a Codificar';
            app.MensajeaCodificarEditField = uieditfield(app.CodificacindeFanoPanel, 'text');
            app.MensajeaCodificarEditField.Value = 'LA PROXIMA SEMANA INICIAN LOS EXAMENES INTERCICLO';
            app.CodificacindeFanoEditFieldLabel = uilabel(app.CodificacindeFanoPanel);
            app.CodificacindeFanoEditFieldLabel.Text = 'Codificación de Fano';
            app.CodificacindeFanoEditField = uieditfield(app.CodificacindeFanoPanel, 'text');
            app.CodificarButton = uibutton(app.CodificacindeFanoPanel, 'push');
            app.CodificarButton.ButtonPushedFcn = createCallbackFcn(app, @CodificarButtonPushed, true);
            app.CodificarButton.Text = 'Codificar';
            app.EntropiaSpinner_4Label = uilabel(app.CodificacindeFanoPanel);
            app.EntropiaSpinner_4Label.Text = 'Entropia';
            app.EntropiaSpinner_4 = uispinner(app.CodificacindeFanoPanel);
            app.LongitudMediaSpinnerLabel = uilabel(app.CodificacindeFanoPanel);
            app.LongitudMediaSpinnerLabel.Text = 'Longitud Media';
            app.LongitudMediaSpinner = uispinner(app.CodificacindeFanoPanel);
            app.EficienciaSpinnerLabel = uilabel(app.CodificacindeFanoPanel);
            app.EficienciaSpinnerLabel.Text = 'Eficiencia';
            app.EficienciaSpinner = uispinner(app.CodificacindeFanoPanel);
            app.CaracterizacinLabel = uilabel(app.CodificacindeFanoPanel);
            app.CaracterizacinLabel.Text = 'Caracterización:';
            app.UITable4 = uitable(app.CodificacindeFanoTab);
            app.UITable4.ColumnName = {'Simbolo'; 'Probabilidad'; 'Codigo Binario'};
            app.UITable4.RowName = {};
            app.CodificacindeHuffmanTab = uitab(app.TabGroup);
            app.CodificacindeHuffmanTab.Title = '8️⃣ Codificación de Huffman';
            app.CodificacindeHuffmanPanel = uipanel(app.CodificacindeHuffmanTab);
            app.CodificacindeHuffmanPanel.Title = 'Codificación de Huffman';
            app.MensajeaCodificarEditField_2Label = uilabel(app.CodificacindeHuffmanPanel);
            app.MensajeaCodificarEditField_2Label.Text = 'Mensaje a Codificar';
            app.MensajeaCodificarEditField_2 = uieditfield(app.CodificacindeHuffmanPanel, 'text');
            app.MensajeaCodificarEditField_2.Value = 'LA PROXIMA SEMANA INICIAN LOS EXAMENES INTERCICLO';
            app.CodificacindeFanoEditField_2Label = uilabel(app.CodificacindeHuffmanPanel);
            app.CodificacindeFanoEditField_2Label.Text = 'Codificación de Fano';
            app.CodificacindeFanoEditField_2 = uieditfield(app.CodificacindeHuffmanPanel, 'text');
            app.CodificarButton_2 = uibutton(app.CodificacindeHuffmanPanel, 'push');
            app.CodificarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CodificarButton_2Pushed, true);
            app.CodificarButton_2.Text = 'Codificar';
            app.EntropiaSpinner_5Label = uilabel(app.CodificacindeHuffmanPanel);
            app.EntropiaSpinner_5Label.Text = 'Entropia';
            app.EntropiaSpinner_5 = uispinner(app.CodificacindeHuffmanPanel);
            app.LongitudMediaSpinner_2Label = uilabel(app.CodificacindeHuffmanPanel);
            app.LongitudMediaSpinner_2Label.Text = 'Longitud Media';
            app.LongitudMediaSpinner_2 = uispinner(app.CodificacindeHuffmanPanel);
            app.EficienciaSpinner_2Label = uilabel(app.CodificacindeHuffmanPanel);
            app.EficienciaSpinner_2Label.Text = 'Eficiencia';
            app.EficienciaSpinner_2 = uispinner(app.CodificacindeHuffmanPanel);
            app.CaracterizacinLabel_2 = uilabel(app.CodificacindeHuffmanPanel);
            app.CaracterizacinLabel_2.Text = 'Caracterización:';
            app.UITable4_2 = uitable(app.CodificacindeHuffmanTab);
            app.UITable4_2.ColumnName = {'Simbolo'; 'Probabilidad'; 'Codigo Binario'};
            app.UITable4_2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Scripts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end